classdef DK_SIEUVIPPRO_SS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NguynDuyKhang2113665Label  matlab.ui.control.Label
        LBSPPanel                  matlab.ui.container.Panel
        a_maxLabel                 matlab.ui.control.Label
        v_maxLabel                 matlab.ui.control.Label
        q_maxLabel                 matlab.ui.control.Label
        Theta1_Box_Val_16          matlab.ui.control.NumericEditField
        Theta1_Box_Val_15          matlab.ui.control.NumericEditField
        Theta1_Box_Val_13          matlab.ui.control.NumericEditField
        InversePanel               matlab.ui.container.Panel
        ClearPathButton            matlab.ui.control.Button
        ZLabel_2                   matlab.ui.control.Label
        YLabel_2                   matlab.ui.control.Label
        XLabel_2                   matlab.ui.control.Label
        InverseButton              matlab.ui.control.Button
        Theta1_Box_Val_12          matlab.ui.control.NumericEditField
        Theta1_Box_Val_11          matlab.ui.control.NumericEditField
        Theta1_Box_Val_10          matlab.ui.control.NumericEditField
        ViewPanel                  matlab.ui.container.Panel
        Theta1_Box_Val_9           matlab.ui.control.NumericEditField
        ZLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        Theta1_Box_Val_8           matlab.ui.control.NumericEditField
        Theta1_Box_Val_7           matlab.ui.control.NumericEditField
        Theta1_Box_Val_5           matlab.ui.control.NumericEditField
        OpacitySlider              matlab.ui.control.Slider
        OpacitySliderLabel         matlab.ui.control.Label
        CoordinateallCheckBox      matlab.ui.control.CheckBox
        Theta1_Box_Val_4           matlab.ui.control.NumericEditField
        Theta1_Box_Val_3           matlab.ui.control.NumericEditField
        Theta1_Box_Val_2           matlab.ui.control.NumericEditField
        YawLabel                   matlab.ui.control.Label
        PitchLabel                 matlab.ui.control.Label
        RollLabel                  matlab.ui.control.Label
        WorkSpaceCheckBox          matlab.ui.control.CheckBox
        ForwardPanel               matlab.ui.container.Panel
        Theta2Slider               matlab.ui.control.Slider
        Theta2SliderLabel          matlab.ui.control.Label
        Theta3Slider               matlab.ui.control.Slider
        Theta3SliderLabel          matlab.ui.control.Label
        Theta3_Box_Val             matlab.ui.control.NumericEditField
        Theta1_Box_Val             matlab.ui.control.NumericEditField
        Theta2_Box_Val             matlab.ui.control.NumericEditField
        Theta1Slider               matlab.ui.control.Slider
        Theta1SliderLabel          matlab.ui.control.Label
        ForwardButton              matlab.ui.control.Button
        UIAxes6_3                  matlab.ui.control.UIAxes
        UIAxes6_2                  matlab.ui.control.UIAxes
        UIAxes6                    matlab.ui.control.UIAxes
        UIAxes3_5                  matlab.ui.control.UIAxes
        UIAxes3_4                  matlab.ui.control.UIAxes
        UIAxes3_3                  matlab.ui.control.UIAxes
        UIAxes3_2                  matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Ma trận xoay hình trụ theo truc y
        rotY = [cos(pi/2)  0 sin(pi/2) 0; 
                0          1 0         0; 
                -sin(pi/2) 0 cos(pi/2) 0; 
                0          0 0         1];


        d     = [160+10 0 0];
        a     = [0 70 70];
        alpha = [pi/2 0 0];
        theta = [0 0 0];
   
        % Cac ma tran bien doi
        A01 = 0; % tt xoay
        A12 = 0; % tt xoay
        A23 = 0; % tt xoay
        A02 = 0; % tt xoay
        A03 = 0; % tt xoay

        path;
        LineHandle;
        SphereHandle = []; % Lưu mặt cầu WorkSpace
        opacity = 1; % Độ mờ của robot
        res = [];

        % Animation cho Robot
        old_theta = [0 0 0];
        new_theta = [0 0 0];

        theta_change;

        % Vi tri cac base
        O0 = [0 0 0]';
        O1;
        O2;
        O3;
        
        K = [];
        x_ref = 0;
        y_ref = 0;
        z_ref = 0;
        
        % Biến cho công thức inverse
        c3;
        s3 = [];
        c2 = [];
        s2 = [];
        % Biến tính cho điểm làm việc của efe cho inverse
        m;
        
        % Trajectory Planning (LSBP)
        q_1 = [];
        q_2 = [];
        q_3 = [];
        v_1 = [];
        v_2 = [];
        v_3 = [];
        a_1 = [];
        a_2 = [];
        a_3 = [];
        t = [];
        t1;
        t2;
        t3;
        v_max;
        v_max1;
        v_max2;
        v_max3;
        a_max;
        
        % Các trục tọa độ Coordinate
        base_quiverX   % Trục X của base
        base_quiverY   % Trục Y của base
        base_quiverZ   % Trục Z của base
        base_textX     % Nhãn trục X của base
        base_textY     % Nhãn trục Y của base
        base_textZ     % Nhãn trục Z của base
        
        joint1_quiverX % Trục X của joint1
        joint1_quiverY % Trục Y của joint1
        joint1_quiverZ % Trục Z của joint1
        joint1_textX   % Nhãn trục X của joint1
        joint1_textY   % Nhãn trục Y của joint1
        joint1_textZ   % Nhãn trục Z của joint1
        
        joint2_quiverX % Trục X của joint2
        joint2_quiverY % Trục Y của joint2
        joint2_quiverZ % Trục Z của joint2
        joint2_textX   % Nhãn trục X của joint2
        joint2_textY   % Nhãn trục Y của joint2
        joint2_textZ   % Nhãn trục Z của joint2
        
        effector_quiverX % Trục X của effector
        effector_quiverY % Trục Y của effector
        effector_quiverZ % Trục Z của effector
        effector_textX   % Nhãn trục X của effector
        effector_textY   % Nhãn trục Y của effector
        effector_textZ   % Nhãn trục Z của effector
    end

    
    methods (Access = private)
        % Ma trận DENAVIT-HARTENBERG
        function result = matrixDenavitHartenberg(app, n)
        result = [cos(app.theta(n)),  -cos(app.alpha(n))*sin(app.theta(n)), sin(app.alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                  sin(app.theta(n)),  cos(app.alpha(n))*cos(app.theta(n)), -sin(app.alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                         0,                    sin(app.alpha(n)),               cos(app.alpha(n)),                     app.d(n);
                         0,                            0,                               0,                                1                 ];
        end
           
        function DrawRobot(app)
            % Xoa hinh robot cu de ve cai moi
            hold(app.UIAxes, "off");
            %% Tinh toan lai cac ma tran DenavitHartenberg
                app.A01 = matrixDenavitHartenberg(app,1);
                app.A12 = matrixDenavitHartenberg(app,2);
                app.A23 = matrixDenavitHartenberg(app,3);

                app.A02 = app.A01*app.A12;
                app.A03 = app.A02*app.A23;
                
            %% Ve Base
                [X, Y, Z] = cylinder(20,50);
                Z = Z * 5; % Tính chiều cao của hình trụ
                % Vẽ hình trụ xoay
                surf(app.UIAxes, X, Y, Z,'FaceColor','b', 'FaceAlpha', app.opacity); 
                hold(app.UIAxes,"on");
                % Vẽ mặt đáy
                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r', 'FaceAlpha', app.opacity);
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r', 'FaceAlpha', app.opacity);
            %% Ve Link 0
                [X, Y, Z] = cylinder(20,50);
                Z = Z * 150; % Tính chiều cao của hình trụ
                %Vẽ hình trụ
                surf(app.UIAxes, X, Y, Z,'FaceColor','r', 'FaceAlpha', app.opacity); % Thay đổi màu sắc nếu cần
                hold(app.UIAxes,"on");
                %Vẽ mặt đáy
                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'r', 'FaceAlpha', app.opacity); % Màu sắc mặt đáy
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'r', 'FaceAlpha', app.opacity); % Màu sắc mặt đáy

            %% Ve joint 0
                [X, Y, Z] = cylinder(20,100);
                Z = Z * 50;
                Z = Z - 50/2;

                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];

                A_new = app.A01*A;
                B_new = app.A01*B;

                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                surf(app.UIAxes, X, Y, Z, 'FaceAlpha', app.opacity);
                hold(app.UIAxes, "on");
                % Vẽ mặt đáy
                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'g', 'FaceAlpha', app.opacity); % Màu sắc mặt đáy
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'g', 'FaceAlpha', app.opacity); % Màu sắc mặt đáy
                
            %% Ve Joint 1
                [X, Y, Z] = cylinder(20,100);
                Z = Z * 50;
                Z = Z - 50/2;

                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
                A_new = app.A02*A;
                B_new = app.A02*B;

                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                surf(app.UIAxes, X, Y, Z, 'FaceAlpha', app.opacity);
                hold(app.UIAxes, "on");

                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'g', 'FaceAlpha', app.opacity);
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'g', 'FaceAlpha', app.opacity);
            %% Ve Link 1
                [X,Y,Z] = cylinder(7);
                Z = Z * 70;
                Z = Z - 70;
                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
                % Xoay quanh truc y 90 do
                A_new = app.rotY*A;
                B_new = app.rotY*B;
                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                % Vua xoay vua tinh tien
                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
                A_new = app.A02*A;
                B_new = app.A02*B;
                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                surf(app.UIAxes, X, Y, Z, 'FaceAlpha', app.opacity); 
                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'b', 'FaceAlpha', app.opacity); 
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'b', 'FaceAlpha', app.opacity); 

            %% Ve link 2
                [X,Y,Z] = cylinder(7);
                Z = Z * 90;
                Z = Z - 70;
                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
                A_new = app.rotY*A;
                B_new = app.rotY*B;
                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                % Xoay link
                A = [X(1,:); Y(1,:); Z(1,:); ones(1,size(X,2))];
                B = [X(2,:); Y(2,:); Z(2,:); ones(1,size(X,2))];
                A_new = app.A03*A;
                B_new = app.A03*B;
                X(1,:) = A_new(1,:);X(2,:) = B_new(1,:);
                Y(1,:) = A_new(2,:);Y(2,:) = B_new(2,:);
                Z(1,:) = A_new(3,:);Z(2,:) = B_new(3,:);
                surf(app.UIAxes, X, Y, Z, 'FaceAlpha', app.opacity);
                fill3(app.UIAxes, X(1, :), Y(1, :), Z(1, :), 'b', 'FaceAlpha', app.opacity);
                fill3(app.UIAxes, X(2, :), Y(2, :), Z(2, :), 'b', 'FaceAlpha', app.opacity); 

            %% Ve Effector
                x_e = [20,30,40,50,60,60,50,40,40,40,50,60,60,50,40,30,20,20,20,20,20,20];
                z_e = [-30,-30,-30,-30,-20,-10,-10,-10,0,10,10,10,20,30,30,30,30,20,10,0,-10,-20];
                y_e = 5*[ones(1,size(x_e,2))];
                y_e(2,:)= -5*[ones(1,size(x_e,2))];

                x_e = repmat(x_e, 2, 1);
                z_e = repmat(z_e, 2, 1); 

                H = [x_e(1,:); y_e(1,:); z_e(1,:); ones(1,size(x_e,2))];
                R = [x_e(2,:); y_e(2,:); z_e(2,:); ones(1,size(x_e,2))];

                H_new = app.A03*R;
                R_new = app.A03*H;
                x_e(1,:) = H_new(1,:);x_e(2,:) = R_new(1,:);
                y_e(1,:) = H_new(2,:);y_e(2,:) = R_new(2,:);
                z_e(1,:) = H_new(3,:);z_e(2,:) = R_new(3,:);
           
                surf(app.UIAxes,x_e, y_e, z_e, 'FaceColor', 'r', 'FaceAlpha', app.opacity);
                fill3(app.UIAxes,x_e(1, :), y_e(1,:), z_e(1,:),'g', 'FaceAlpha', app.opacity);
                fill3(app.UIAxes,x_e(2, :), y_e(2,:), z_e(2,:),'g', 'FaceAlpha', app.opacity);

            %% Ve Path
                x_p= 60;
                y_p= 0;
                z_p= 0;
                P= [x_p;y_p;z_p;1];
                P = app.A03*P;
                x_p = P(1,1);
                y_p = P(2,1);
                z_p = P(3,1);
            %% Toa do X,Y,Z
                app.Theta1_Box_Val_2.Value = x_p;
                app.Theta1_Box_Val_3.Value = y_p;
                app.Theta1_Box_Val_4.Value = z_p;

                [~, n] = size(app.path);
                app.path(1,n+1) = x_p;
                app.path(2,n+1) = y_p;
                app.path(3,n+1) = z_p;
                plot3(app.UIAxes,app.path(1,:),app.path(2,:),app.path(3,:),'LineWidth',2);

             %% Toa do Roll, Pitch, Yaw
                cal = sqrt((app.A03(3,2))^2 + (app.A03(3,3))^2);
                pitch = atan2(-app.A03(3,1),cal);
                if pitch == pi/2
                    yaw = 0;
                    roll = atan2(app.A03(1,2),app.A03(2,2));
                elseif pitch == -pi/2
                    yaw = 0;
                    roll = -atan2(app.A03(1,2),app.A03(2,2));
                else
                    yaw = atan2(app.A03(2,1)/cos(pitch), app.A03(1,1)/cos(pitch));
                    roll = atan2(app.A03(3,2)/cos(pitch), app.A03(3,3)/cos(pitch));
                end
                app.res = [roll pitch yaw]*(180/pi);

                app.Theta1_Box_Val_5.Value = app.res(1);
                app.Theta1_Box_Val_7.Value = app.res(2);
                app.Theta1_Box_Val_8.Value = app.res(3);
        end
      %% Trajectory_planning LSPB
        function LSPB(app)
        app.q_1 = [];
        app.q_2 = [];
        app.q_3 = [];
        app.v_1 = [];
        app.v_2 = [];
        app.v_3 = [];
        app.a_1 = [];
        app.a_2 = [];
        app.a_3 = [];
        app.t = [];
        app.v_max1 = app.v_max;
        app.v_max2 = app.v_max;
        app.v_max3 = app.v_max;

        tmp1 = abs(app.new_theta(1)-app.old_theta(1));
        if (tmp1 ~=0)
        if app.v_max > sqrt(tmp1*app.a_max)   %% Theo don vi
           app.v_max1 = sqrt(tmp1*app.a_max);
        end
        tc1 = app.v_max1/app.a_max;
        qc1 = 1/2*app.a_max*tc1^2;
        t_m1 = (tmp1-2*qc1)/app.v_max1;
        tf1 = 2*tc1+t_m1;
        else
        tc1 = 0;
        t_m1 = 0;
        tf1 = 0;
        end

        tmp2 = abs(app.new_theta(2)-app.old_theta(2));
        if (tmp2 ~=0)
        if app.v_max > sqrt(tmp2*app.a_max)   %% Theo don vi
           app.v_max2 = sqrt(tmp2*app.a_max);
        end
        tc2 = app.v_max2/app.a_max;
        qc2 = 1/2*app.a_max*tc2^2;
        t_m2 = (tmp2-2*qc2)/app.v_max2;
        tf2 = 2*tc2+t_m2;
        else
        tf2 = 0;  
        tc2 = 0;
        t_m2 = 0;
        end

        tmp3 = abs(app.new_theta(3)-app.old_theta(3));
        if(tmp3 ~=0)
        if app.v_max > sqrt(tmp3*app.a_max)   %% Theo don vi
           app.v_max3 = sqrt(tmp3*app.a_max);
        end
        tc3 = app.v_max3/app.a_max;
        qc3 = 1/2*app.a_max*tc3^2;
        t_m3 = (tmp3-2*qc3)/app.v_max3;
        tf3 = 2*tc3+t_m3;
        else
        tf3 = 0;
        tc3 = 0;
        t_m3 = 0;
        end

        T_max = max([tf1 tf2 tf3]);
        
        app.t = 0:0.01:T_max;
        for i=1:length(app.t)
            %% joint 1
            t_ve(i) = app.t(i);
            if(tmp1 ~= 0)
            if app.t(i) < tc1
                app.q_1(i) = 1/2*app.a_max*app.t(i)^2;
                app.v_1(i) = app.a_max*app.t(i);
                app.a_1(i) = app.a_max;
            elseif app.t(i) < tc1 + t_m1
                app.q_1(i) = qc1 + app.v_max1*(app.t(i)-tc1);
                app.v_1(i) = app.v_max1;
                app.a_1(i) = 0;
            elseif app.t <= tf1
                app.q_1(i) = tmp1 - 1/2*app.a_max*(tf1-app.t(i))^2;
                app.v_1(i) = app.a_max*(tf1-app.t(i));
                app.a_1(i) = -app.a_max;
            else
                app.q_1(i) = tmp1;
                app.v_1(i) = 0;
                app.a_1(i) = 0;
            end
            else
                app.q_1(i) = tmp1;
                app.v_1(i) = 0;
                app.a_1(i) = 0;
            end
            %% joint 2
            if(tmp2 ~= 0) % lỗi không tham đổi 1 tham số
            if app.t(i) < tc2
                app.q_2(i) = 1/2*app.a_max*app.t(i)^2;
                app.v_2(i) = app.a_max*app.t(i);
                app.a_2(i) = app.a_max;
            elseif app.t(i) < tc2 + t_m2
                app.q_2(i) = qc2 + app.v_max2*(app.t(i)-tc2);
                app.v_2(i) = app.v_max2;
                app.a_2(i) = 0;
            elseif app.t(i) <= tf2
                app.q_2(i) = tmp2 - 1/2*app.a_max*(tf2-app.t(i))^2;
                app.v_2(i) = app.a_max*(tf2-app.t(i));
                app.a_2(i) = -app.a_max;
            else
                app.q_2(i) = tmp2;
                app.v_2(i) = 0;
                app.a_2(i) = 0;
            end
            else % không thay đổi 1 tham số
                app.q_2(i) = tmp2;
                app.v_2(i) = 0;
                app.a_2(i) = 0;
            end
            %% joint 3
            if(tmp3 ~= 0)
            if app.t(i) < tc3
                app.q_3(i) = 1/2*app.a_max*app.t(i)^2;
                app.v_3(i) = app.a_max*app.t(i);
                app.a_3(i) = app.a_max;
            elseif app.t(i) < tc3 + t_m3
                app.q_3(i) = qc3 + app.v_max3*(app.t(i)-tc3);
                app.v_3(i) = app.v_max3;
                app.a_3(i) = 0;
            elseif app.t(i) <= tf3
                app.q_3(i) = tmp3 - 1/2*app.a_max*(tf3-app.t(i))^2;
                app.v_3(i) = app.a_max*(tf3-app.t(i));
                app.a_3(i) = -app.a_max;
            else
                app.q_3(i) = tmp3;
                app.v_3(i) = 0;
                app.a_3(i) = 0;
            end
            else 
                app.q_3(i) = tmp3;
                app.v_3(i) = 0;
                app.a_3(i) = 0;
            end

            if(mod(i,10) == 0 || i == length(app.t))
            %% Theta1 and Omega1
            if(app.new_theta(1)-app.old_theta(1)>=0)
            app.theta(1) = app.old_theta(1)+ app.q_1(i);
            end
            if(app.new_theta(1)-app.old_theta(1)<0)
            app.theta(1) = app.old_theta(1) - app.q_1(i);
            end
            if(app.new_theta(1)-app.old_theta(1)>=0)
                plot(app.UIAxes3,t_ve,rad2deg(app.q_1+app.old_theta(1)));
                plot(app.UIAxes3_4,t_ve,rad2deg(app.v_1));
                plot(app.UIAxes6,t_ve,rad2deg(app.a_1));
            end
            if(app.new_theta(1)-app.old_theta(1)<0)
                plot(app.UIAxes3,t_ve,rad2deg(-app.q_1+app.old_theta(1)));
                plot(app.UIAxes3_4,t_ve,rad2deg(-app.v_1));
                plot(app.UIAxes6,t_ve,rad2deg(-app.a_1));
            end
            %% Theta2 and Omega2
            if(app.new_theta(2)-app.old_theta(2)>=0)
            app.theta(2) = app.old_theta(2)+ app.q_2(i);
            end
            if(app.new_theta(2)-app.old_theta(2)<0)
            app.theta(2) = app.old_theta(2) - app.q_2(i);
            end
            if(app.new_theta(2)-app.old_theta(2)>=0)
                plot(app.UIAxes3_2,t_ve,rad2deg(app.q_2+app.old_theta(2)));
                plot(app.UIAxes3_5,t_ve,rad2deg(app.v_2));
                plot(app.UIAxes6_2,t_ve,rad2deg(app.a_2));
            end
            if(app.new_theta(2)-app.old_theta(2)<0)
                plot(app.UIAxes3_2,t_ve,rad2deg(-app.q_2+app.old_theta(2)));
                plot(app.UIAxes3_5,t_ve,rad2deg(-app.v_2));
                plot(app.UIAxes6_2,t_ve,rad2deg(-app.a_2));
            end
            %% Theta3 and Omega3
            if(app.new_theta(3)-app.old_theta(3)>=0)
            app.theta(3) = app.old_theta(3)+ app.q_3(i);
            end
            if(app.new_theta(3)-app.old_theta(3)<0)
            app.theta(3) = app.old_theta(3) - app.q_3(i);
            end
            if(app.new_theta(3)-app.old_theta(3)>=0)
                plot(app.UIAxes3_3,t_ve,rad2deg(app.q_3+app.old_theta(3)));
                plot(app.UIAxes2,t_ve,rad2deg(app.v_3));
                plot(app.UIAxes6_3,t_ve,rad2deg(app.a_3));
            end
            if(app.new_theta(3)-app.old_theta(3)<0)
                plot(app.UIAxes3_3,t_ve,rad2deg(-app.q_3+app.old_theta(3)));
                plot(app.UIAxes2,t_ve,rad2deg(-app.v_3));
                plot(app.UIAxes6_3,t_ve,rad2deg(-app.a_3));
            end
            app.DrawRobot();
            end
             pause(0.01)
        end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DrawRobot();
            app.Theta1_Box_Val_15.Value = 2;
            app.Theta1_Box_Val_16.Value = 2;
            app.v_max = 2;
            app.a_max = 2;

        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)

            app.Theta1_Box_Val.Value = app.Theta1Slider.Value;
            
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)

            app.Theta2_Box_Val.Value = app.Theta2Slider.Value;

        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
   
            app.Theta3_Box_Val.Value = app.Theta3Slider.Value;

        end

        % Value changed function: Theta1_Box_Val
        function Theta1_Box_ValValueChanged(app, event)

            app.Theta1Slider.Value = app.Theta1_Box_Val.Value;

        end

        % Value changed function: Theta2_Box_Val
        function Theta2_Box_ValValueChanged(app, event)

            app.Theta2Slider.Value = app.Theta2_Box_Val.Value;

        end

        % Value changed function: Theta3_Box_Val
        function Theta3_Box_ValValueChanged(app, event)

            app.Theta3Slider.Value = app.Theta3_Box_Val.Value;
          
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)

        app.old_theta(1) = app.new_theta(1);
        app.new_theta(1) = deg2rad(app.Theta1Slider.Value);

        app.old_theta(2) = app.new_theta(2);
        app.new_theta(2) = deg2rad(app.Theta2Slider.Value);

        app.old_theta(3) = app.new_theta(3);
        app.new_theta(3) = deg2rad(app.Theta3Slider.Value);

        app.LSPB();

        end

        % Value changed function: WorkSpaceCheckBox
        function WorkSpaceCheckBoxValueChanged(app, event)
            value = app.WorkSpaceCheckBox.Value;  % Kiểm tra giá trị của CheckBox
            if value  % Nếu CheckBox được tích chọn
                r = 200;
                [u, v] = meshgrid(linspace(0, 2*pi, 30), linspace(0, pi, 30));
                x = r .* cos(u) .* sin(v);
                y = r .* sin(u) .* sin(v);
                z = r .* cos(v) + 170;
                color = [0.5 0.5 0.5];
                % Vẽ mặt cầu trên UIAxes và lưu handle vào biến SphereHandle
                app.SphereHandle = surf(app.UIAxes, x, y, z, 'EdgeColor', 'none', 'Facecolor', color, 'Facealpha', 0.15);
            else
                % Nếu CheckBox không được tích, chỉ xóa mặt cầu mà không xóa các đối tượng khác
                if ~isempty(app.SphereHandle)  % Kiểm tra xem mặt cầu có tồn tại không
                    delete(app.SphereHandle);  % Xóa đối tượng mặt cầu
                    app.SphereHandle = [];  % Đặt lại biến SphereHandle
                end
            end
        end

        % Value changed function: CoordinateallCheckBox
        function CoordinateallCheckBoxValueChanged(app, event)
          % Trong hàm callback của checkbox
          value = app.CoordinateallCheckBox.Value; % Kiểm tra trạng thái của checkbox
        
        if value
            % Vẽ các trục tọa độ x y z cho base
            x_axis = [100 0 0 1]'; % Trục X
            y_axis = [0 100 0 1]'; % Trục Y
            z_axis = [0 0 100 1]'; % Trục Z
            origin = [0 0 0 1]'; % Gốc tọa độ
        
            % Tính tọa độ mới của các trục sau khi chuyển đổi
            new_x_axis = x_axis;
            new_y_axis = y_axis;
            new_z_axis = z_axis;
            new_origin = origin;
        
            % Vẽ các trục đã chuyển đổi và lưu đối tượng để xóa sau này
            app.base_quiverX = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
            app.base_quiverY = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
            app.base_quiverZ = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
        
            % Gắn các nhãn
            app.base_textX = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
            app.base_textY = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
            app.base_textZ = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
            
            % Vẽ các trục cho joint1
            x_axis = [100 0 0 1]'; 
            y_axis = [0 100 0 1]'; 
            z_axis = [0 0 100 1]'; 
            origin = [0 0 0 1]'; 
            
            new_x_axis = app.A01 * x_axis;
            new_y_axis = app.A01 * y_axis;
            new_z_axis = app.A01 * z_axis;
            new_origin = app.A01 * origin;
        
            app.joint1_quiverX = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
            app.joint1_quiverY = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
            app.joint1_quiverZ = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
            app.joint1_textX = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
            app.joint1_textY = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
            app.joint1_textZ = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
            
            % Vẽ các trục cho joint2
            x_axis = [100 0 0 1]'; 
            y_axis = [0 100 0 1]'; 
            z_axis = [0 0 100 1]'; 
            origin = [0 0 0 1]'; 
            
            new_x_axis = app.A02 * x_axis;
            new_y_axis = app.A02 * y_axis;
            new_z_axis = app.A02 * z_axis;
            new_origin = app.A02 * origin;
        
            app.joint2_quiverX = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
            app.joint2_quiverY = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
            app.joint2_quiverZ = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
            app.joint2_textX = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
            app.joint2_textY = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
            app.joint2_textZ = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
        
            % Vẽ các trục cho effector
            x_axis = [160 0 0 1]'; 
            y_axis = [60 100 0 1]'; 
            z_axis = [60 0 100 1]'; 
            origin = [60 0 0 1]'; 
            
            new_x_axis = app.A03 * x_axis;
            new_y_axis = app.A03 * y_axis;
            new_z_axis = app.A03 * z_axis;
            new_origin = app.A03 * origin;
        
            app.effector_quiverX = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_x_axis(1)-new_origin(1), new_x_axis(2)-new_origin(2), new_x_axis(3)-new_origin(3), 'r', 'LineWidth', 2);
            app.effector_quiverY = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_y_axis(1)-new_origin(1), new_y_axis(2)-new_origin(2), new_y_axis(3)-new_origin(3), 'g', 'LineWidth', 2);
            app.effector_quiverZ = quiver3(app.UIAxes, new_origin(1), new_origin(2), new_origin(3), new_z_axis(1)-new_origin(1), new_z_axis(2)-new_origin(2), new_z_axis(3)-new_origin(3), 'b', 'LineWidth', 2);
            app.effector_textX = text(app.UIAxes, new_x_axis(1), new_x_axis(2), new_x_axis(3), 'X', 'FontSize', 12, 'Color', 'r');
            app.effector_textY = text(app.UIAxes, new_y_axis(1), new_y_axis(2), new_y_axis(3), 'Y', 'FontSize', 12, 'Color', 'g');
            app.effector_textZ = text(app.UIAxes, new_z_axis(1), new_z_axis(2), new_z_axis(3), 'Z', 'FontSize', 12, 'Color', 'b');
            
        else
            % Xóa các trục và nhãn đã vẽ nếu checkbox không được chọn
            if  isvalid(app.base_quiverX)
                delete(app.base_quiverX);
                delete(app.base_quiverY);
                delete(app.base_quiverZ);
                delete(app.base_textX);
                delete(app.base_textY);
                delete(app.base_textZ);
            end
            
            if  isvalid(app.joint1_quiverX)
                delete(app.joint1_quiverX);
                delete(app.joint1_quiverY);
                delete(app.joint1_quiverZ);
                delete(app.joint1_textX);
                delete(app.joint1_textY);
                delete(app.joint1_textZ);
            end
        
            if  isvalid(app.joint2_quiverX)
                delete(app.joint2_quiverX);
                delete(app.joint2_quiverY);
                delete(app.joint2_quiverZ);
                delete(app.joint2_textX);
                delete(app.joint2_textY);
                delete(app.joint2_textZ);
            end
            
            if  isvalid(app.effector_quiverX)
                delete(app.effector_quiverX);
                delete(app.effector_quiverY);
                delete(app.effector_quiverZ);
                delete(app.effector_textX);
                delete(app.effector_textY);
                delete(app.effector_textZ);
            end
        end


        end

        % Value changed function: OpacitySlider
        function OpacitySliderValueChanged(app, event)
           
            app.opacity = app.OpacitySlider.Value;
            app.Theta1_Box_Val_9.Value = app.opacity;
            app.DrawRobot();
            
        end

        % Value changed function: Theta1_Box_Val_9
        function Theta1_Box_Val_9ValueChanged2(app, event)

             app.opacity = app.Theta1_Box_Val_9.Value;
             app.OpacitySlider.Value = app.opacity;
         
        end

        % Button pushed function: ClearPathButton
        function ClearPathButtonPushed(app, event)
     
            app.path = [];
            app.DrawRobot();

        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)

            app.m = app.a(3)+60;
            % Hoan Chinh
            app.theta(1) = rad2deg(atan2(app.y_ref, app.x_ref));
            app.Theta1_Box_Val.Value = app.theta(1);
            app.Theta1Slider.Value =app.theta(1);
            app.theta(1) = atan2(app.y_ref, app.x_ref);
            % Calculate intermediate values for theta2 and theta3
            pwx2_pwy2 = app.x_ref^2 + app.y_ref^2;
            pwx2_pwy2_pwz2 = app.z_ref^2 + pwx2_pwy2;
            
            % Calculate theta3 using c3 and s3
            app.c3 = (pwx2_pwy2_pwz2 - app.a(2)^2 - app.m^2) / (2 * app.a(2) * app.m);
            app.s3 = [sqrt(1 - app.c3^2); -sqrt(1 - app.c3^2)];

%             if app.z_ref >= 0
%                 app.Theta3_Box_Val.Value = app.theta(3);
%                 app.Theta3Slider.Value =app.theta(3);
%                 app.theta(3) = atan2(app.s3(1), app.c3);
% 
%                 % Calculate theta2 using c2 and s2
%                 app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));
% 
%                 if (app.theta(2) <-30 || app.theta(2)>90 )
%                 app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));
%                 end
%             else
%                 app.Theta3_Box_Val.Value = app.theta(3);
%                 app.Theta3Slider.Value =app.theta(3);
%                 app.theta(3) = atan2(app.s3(2), app.c3);
% 
%                 app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));
% 
%                 if (app.theta(2) <-30 || app.theta(2)>90 )
%                 app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));
%                 end
%             end

             if app.z_ref >= 0
                app.Theta3_Box_Val.Value = app.theta(3);
                app.Theta3Slider.Value =app.theta(3);
                app.theta(3) = atan2(app.s3(1), app.c3);

                % Calculate theta2 using c2 and s2
%                 app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                if (app.theta(2) <-30 || app.theta(2)>90 )
%                 app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(1)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(1))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));
                end
            else
                app.Theta3_Box_Val.Value = app.theta(3);
                app.Theta3Slider.Value =app.theta(3);
                app.theta(3) = atan2(app.s3(2), app.c3);
 
%                 app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                if (app.theta(2) <-30 || app.theta(2)>90 )
%                 app.c2 = (sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.s2 = ((-sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m*app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
%                 app.theta(2) = rad2deg(atan2(app.s2, app.c2));

                app.c2 = (-sqrt(pwx2_pwy2) * (app.a(2) + app.m * app.c3) + app.z_ref * app.m * app.s3(2)) / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.s2 = ((sqrt(pwx2_pwy2) * app.m * app.s3(2))+ app.z_ref * (app.a(2) + app.m * app.c3))  / (app.a(2)^2 + app.m^2 + 2 * app.a(2) * app.m * app.c3);
                app.theta(2) = rad2deg(atan2(app.s2, app.c2));
                end
            end

        app.Theta2_Box_Val.Value = app.theta(2);
        app.Theta2Slider.Value =app.theta(2);
        app.theta(2) = atan2(app.s2, app.c2);

        app.old_theta(1) = app.new_theta(1);
        app.new_theta(1) = app.theta(1);
        app.Theta1Slider.Value = rad2deg(app.new_theta(1));
        app.Theta1_Box_Val.Value = app.Theta1Slider.Value;

        app.old_theta(2) = app.new_theta(2);
        app.new_theta(2) = app.theta(2);
        app.Theta2Slider.Value = rad2deg(app.new_theta(2));
        app.Theta2_Box_Val.Value = app.Theta2Slider.Value;

        app.old_theta(3) = app.new_theta(3);
        app.new_theta(3) = app.theta(3);
        app.Theta3Slider.Value = rad2deg(app.new_theta(3));
        app.Theta3_Box_Val.Value = app.Theta3Slider.Value;

        app.LSPB();

        end

        % Value changed function: Theta1_Box_Val_10
        function Theta1_Box_Val_10ValueChanged(app, event)
            app.x_ref = app.Theta1_Box_Val_10.Value;
            
        end

        % Value changed function: Theta1_Box_Val_11
        function Theta1_Box_Val_11ValueChanged(app, event)
            app.y_ref = app.Theta1_Box_Val_11.Value;
            
        end

        % Value changed function: Theta1_Box_Val_12
        function Theta1_Box_Val_12ValueChanged(app, event)
            app.z_ref = app.Theta1_Box_Val_12.Value;
            app.z_ref = app.z_ref - app.d(1);
        end

        % Value changed function: Theta1_Box_Val_13
        function Theta1_Box_Val_13ValueChanged(app, event)
%             value = app.Theta1_Box_Val_13.Value;
            
        end

        % Value changed function: Theta1_Box_Val_15
        function Theta1_Box_Val_15ValueChanged(app, event)
            app.v_max = app.Theta1_Box_Val_15.Value;
            
        end

        % Value changed function: Theta1_Box_Val_16
        function Theta1_Box_Val_16ValueChanged(app, event)
            app.a_max = app.Theta1_Box_Val_16.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ROBOT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [0 500];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.ZTick = [0 50 100 150 200 250 300 350];
            app.UIAxes.ZTickLabel = {'0'; '50'; '100'; '150'; '200'; '200'; '250'; '300'; '350'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Omega3')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Theta1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Theta2')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Theta3')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.UIFigure);
            title(app.UIAxes3_4, 'Omega1')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes3_5 = uiaxes(app.UIFigure);
            title(app.UIAxes3_5, 'Omega2')
            xlabel(app.UIAxes3_5, 'X')
            ylabel(app.UIAxes3_5, 'Y')
            zlabel(app.UIAxes3_5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Alpha1')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6_2 = uiaxes(app.UIFigure);
            title(app.UIAxes6_2, 'Alpha2')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes6_3 = uiaxes(app.UIFigure);
            title(app.UIAxes6_3, 'Alpha3')
            xlabel(app.UIAxes6_3, 'X')
            ylabel(app.UIAxes6_3, 'Y')
            zlabel(app.UIAxes6_3, 'Z')
            app.ForwardPanel = uipanel(app.UIFigure);
            app.ForwardPanel.Title = 'Forward';
            app.ForwardButton = uibutton(app.ForwardPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Theta1SliderLabel = uilabel(app.ForwardPanel);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.ForwardPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2_Box_Val = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta2_Box_Val.ValueChangedFcn = createCallbackFcn(app, @Theta2_Box_ValValueChanged, true);
            app.Theta1_Box_Val = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta1_Box_Val.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_ValValueChanged, true);
            app.Theta3_Box_Val = uieditfield(app.ForwardPanel, 'numeric');
            app.Theta3_Box_Val.ValueChangedFcn = createCallbackFcn(app, @Theta3_Box_ValValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.ForwardPanel);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.ForwardPanel);
            app.Theta3Slider.Limits = [-45 45];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.ForwardPanel);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.ForwardPanel);
            app.Theta2Slider.Limits = [-30 90];
            app.Theta2Slider.MajorTicks = [-30 -10 10 30 50 70 90];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta2Slider.MinorTicks = [-30 -26 -22 -18 -14 -10 -6 -2 2 6 10 14 18 22 26 30 34 38 42 46 50 54 58 62 66 70 74 78 82 86 90];
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.WorkSpaceCheckBox = uicheckbox(app.ViewPanel);
            app.WorkSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceCheckBoxValueChanged, true);
            app.WorkSpaceCheckBox.Text = 'WorkSpace';
            app.RollLabel = uilabel(app.ViewPanel);
            app.RollLabel.Text = 'Roll';
            app.PitchLabel = uilabel(app.ViewPanel);
            app.PitchLabel.Text = 'Pitch';
            app.YawLabel = uilabel(app.ViewPanel);
            app.YawLabel.Text = 'Yaw';
            app.Theta1_Box_Val_2 = uieditfield(app.ViewPanel, 'numeric');
            app.Theta1_Box_Val_3 = uieditfield(app.ViewPanel, 'numeric');
            app.Theta1_Box_Val_4 = uieditfield(app.ViewPanel, 'numeric');
            app.CoordinateallCheckBox = uicheckbox(app.ViewPanel);
            app.CoordinateallCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoordinateallCheckBoxValueChanged, true);
            app.CoordinateallCheckBox.Text = 'Coordinate all';
            app.OpacitySliderLabel = uilabel(app.ViewPanel);
            app.OpacitySliderLabel.Text = 'Opacity';
            app.OpacitySlider = uislider(app.ViewPanel);
            app.OpacitySlider.Limits = [0 1];
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacitySliderValueChanged, true);
            app.Theta1_Box_Val_5 = uieditfield(app.ViewPanel, 'numeric');
            app.Theta1_Box_Val_7 = uieditfield(app.ViewPanel, 'numeric');
            app.Theta1_Box_Val_8 = uieditfield(app.ViewPanel, 'numeric');
            app.XLabel = uilabel(app.ViewPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.ViewPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.ViewPanel);
            app.ZLabel.Text = 'Z';
            app.Theta1_Box_Val_9 = uieditfield(app.ViewPanel, 'numeric');
            app.Theta1_Box_Val_9.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_9ValueChanged2, true);
            app.InversePanel = uipanel(app.UIFigure);
            app.InversePanel.Title = 'Inverse';
            app.Theta1_Box_Val_10 = uieditfield(app.InversePanel, 'numeric');
            app.Theta1_Box_Val_10.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_10ValueChanged, true);
            app.Theta1_Box_Val_11 = uieditfield(app.InversePanel, 'numeric');
            app.Theta1_Box_Val_11.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_11ValueChanged, true);
            app.Theta1_Box_Val_12 = uieditfield(app.InversePanel, 'numeric');
            app.Theta1_Box_Val_12.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_12ValueChanged, true);
            app.InverseButton = uibutton(app.InversePanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.XLabel_2 = uilabel(app.InversePanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.InversePanel);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.InversePanel);
            app.ZLabel_2.Text = 'Z';
            app.ClearPathButton = uibutton(app.InversePanel, 'push');
            app.ClearPathButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPathButtonPushed, true);
            app.ClearPathButton.Text = 'Clear Path';
            app.LBSPPanel = uipanel(app.UIFigure);
            app.LBSPPanel.Title = 'LBSP';
            app.Theta1_Box_Val_13 = uieditfield(app.LBSPPanel, 'numeric');
            app.Theta1_Box_Val_13.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_13ValueChanged, true);
            app.Theta1_Box_Val_15 = uieditfield(app.LBSPPanel, 'numeric');
            app.Theta1_Box_Val_15.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_15ValueChanged, true);
            app.Theta1_Box_Val_16 = uieditfield(app.LBSPPanel, 'numeric');
            app.Theta1_Box_Val_16.ValueChangedFcn = createCallbackFcn(app, @Theta1_Box_Val_16ValueChanged, true);
            app.q_maxLabel = uilabel(app.LBSPPanel);
            app.q_maxLabel.Text = 'q_max';
            app.v_maxLabel = uilabel(app.LBSPPanel);
            app.v_maxLabel.Text = 'v_max';
            app.a_maxLabel = uilabel(app.LBSPPanel);
            app.a_maxLabel.Text = 'a_max';
            app.NguynDuyKhang2113665Label = uilabel(app.UIFigure);
            app.NguynDuyKhang2113665Label.Text = {'Nguyễn Duy Khang'; '2113665'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DK_SIEUVIPPRO_SS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end